classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InitializationTab               matlab.ui.container.Tab
        FlightConditionsPanel           matlab.ui.container.Panel
        AmbientTemperatureKEditFieldLabel  matlab.ui.control.Label
        AmbientTemperatureKEditField    matlab.ui.control.NumericEditField
        AmbientPressurekPaEditFieldLabel  matlab.ui.control.Label
        AmbientPressurekPaEditField     matlab.ui.control.NumericEditField
        MachNumberEditFieldLabel        matlab.ui.control.Label
        MachNumberEditField             matlab.ui.control.NumericEditField
        FuelPressurekPaEditFieldLabel   matlab.ui.control.Label
        FuelPressurekPaEditField        matlab.ui.control.NumericEditField
        PressureRatiosPanel             matlab.ui.container.Panel
        FanEditFieldLabel               matlab.ui.control.Label
        FanEditField                    matlab.ui.control.NumericEditField
        CompressorEditFieldLabel        matlab.ui.control.Label
        CompressorEditField             matlab.ui.control.NumericEditField
        CombustorEditField_4Label       matlab.ui.control.Label
        CombustorEditField_4            matlab.ui.control.NumericEditField
        AfterburnerEditFieldLabel       matlab.ui.control.Label
        AfterburnerEditField            matlab.ui.control.NumericEditField
        NozzleMixerEditField_2Label     matlab.ui.control.Label
        NozzleMixerEditField_2          matlab.ui.control.NumericEditField
        MassRatiosPanel                 matlab.ui.container.Panel
        CombustorfuelairEditFieldLabel  matlab.ui.control.Label
        CombustorfuelairEditField       matlab.ui.control.NumericEditField
        AfterburnerfuelairEditFieldLabel  matlab.ui.control.Label
        AfterburnerfuelairEditField     matlab.ui.control.NumericEditField
        FanBypassEditFieldLabel         matlab.ui.control.Label
        FanBypassEditField              matlab.ui.control.NumericEditField
        CompressorBleedEditFieldLabel   matlab.ui.control.Label
        CompressorBleedEditField        matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        CombustionChamberPanel          matlab.ui.container.Panel
        MaxTemperatureKEditFieldLabel   matlab.ui.control.Label
        MaxTemperatureKEditField        matlab.ui.control.NumericEditField
        AfterburnerMaxTempKEditFieldLabel  matlab.ui.control.Label
        AfterburnerMaxTempKEditField    matlab.ui.control.NumericEditField
        FuelHeatingValueMJkgEditFieldLabel  matlab.ui.control.Label
        FuelHeatingValueMJkgEditField   matlab.ui.control.NumericEditField
        BypassNozzleSwitchLabel         matlab.ui.control.Label
        BypassNozzleSwitch              matlab.ui.control.Switch
        EngineTypeSwitchLabel           matlab.ui.control.Label
        EngineTypeSwitch                matlab.ui.control.Switch
        ConstantsTab                    matlab.ui.container.Tab
        SpecificHeatRatioPanel          matlab.ui.container.Panel
        DiffuserEditFieldLabel          matlab.ui.control.Label
        DiffuserEditField               matlab.ui.control.NumericEditField
        FanEditField_2Label             matlab.ui.control.Label
        FanEditField_2                  matlab.ui.control.NumericEditField
        CompressorEditField_2Label      matlab.ui.control.Label
        CompressorEditField_2           matlab.ui.control.NumericEditField
        CombustorEditFieldLabel         matlab.ui.control.Label
        CombustorEditField              matlab.ui.control.NumericEditField
        TurbineEditFieldLabel           matlab.ui.control.Label
        TurbineEditField                matlab.ui.control.NumericEditField
        TurbineMixerEditFieldLabel      matlab.ui.control.Label
        TurbineMixerEditField           matlab.ui.control.NumericEditField
        FanTurbineEditFieldLabel        matlab.ui.control.Label
        FanTurbineEditField             matlab.ui.control.NumericEditField
        AfterburnerEditField_2Label     matlab.ui.control.Label
        AfterburnerEditField_2          matlab.ui.control.NumericEditField
        CoreNozzleEditFieldLabel        matlab.ui.control.Label
        CoreNozzleEditField             matlab.ui.control.NumericEditField
        FanNozzleEditFieldLabel         matlab.ui.control.Label
        FanNozzleEditField              matlab.ui.control.NumericEditField
        CombinedNozzleEditFieldLabel    matlab.ui.control.Label
        CombinedNozzleEditField         matlab.ui.control.NumericEditField
        MolecularWeightsgmolPanel       matlab.ui.container.Panel
        DiffuserEditField_2Label        matlab.ui.control.Label
        DiffuserEditField_2             matlab.ui.control.NumericEditField
        FanEditField_3Label             matlab.ui.control.Label
        FanEditField_3                  matlab.ui.control.NumericEditField
        CompressorEditField_3Label      matlab.ui.control.Label
        CompressorEditField_3           matlab.ui.control.NumericEditField
        CombustorEditField_3Label       matlab.ui.control.Label
        CombustorEditField_3            matlab.ui.control.NumericEditField
        TurbineEditField_2Label         matlab.ui.control.Label
        TurbineEditField_2              matlab.ui.control.NumericEditField
        TurbineMixerEditField_2Label    matlab.ui.control.Label
        TurbineMixerEditField_2         matlab.ui.control.NumericEditField
        FanTurbineEditField_2Label      matlab.ui.control.Label
        FanTurbineEditField_2           matlab.ui.control.NumericEditField
        AfterburnerEditField_3Label     matlab.ui.control.Label
        AfterburnerEditField_3          matlab.ui.control.NumericEditField
        CoreNozzleEditField_2Label      matlab.ui.control.Label
        CoreNozzleEditField_2           matlab.ui.control.NumericEditField
        FanNozzleEditField_2Label       matlab.ui.control.Label
        FanNozzleEditField_2            matlab.ui.control.NumericEditField
        NozzleMixerEditFieldLabel       matlab.ui.control.Label
        NozzleMixerEditField            matlab.ui.control.NumericEditField
        CombinedNozzleEditField_2Label  matlab.ui.control.Label
        CombinedNozzleEditField_2       matlab.ui.control.NumericEditField
        EfficienciesPanel               matlab.ui.container.Panel
        DiffuserEditField_3Label        matlab.ui.control.Label
        DiffuserEditField_3             matlab.ui.control.NumericEditField
        FanEditField_4Label             matlab.ui.control.Label
        FanEditField_4                  matlab.ui.control.NumericEditField
        CoreNozzleEditField_3Label      matlab.ui.control.Label
        CoreNozzleEditField_3           matlab.ui.control.NumericEditField
        CompressorEditField_4Label      matlab.ui.control.Label
        CompressorEditField_4           matlab.ui.control.NumericEditField
        CombustorEditField_2Label       matlab.ui.control.Label
        CombustorEditField_2            matlab.ui.control.NumericEditField
        TurbineEditField_3Label         matlab.ui.control.Label
        TurbineEditField_3              matlab.ui.control.NumericEditField
        FanTurbineEditField_3Label      matlab.ui.control.Label
        FanTurbineEditField_3           matlab.ui.control.NumericEditField
        AfterburnerEditField_4Label     matlab.ui.control.Label
        AfterburnerEditField_4          matlab.ui.control.NumericEditField
        FanNozzleEditField_3Label       matlab.ui.control.Label
        FanNozzleEditField_3            matlab.ui.control.NumericEditField
        CombinedNozzleEditField_3Label  matlab.ui.control.Label
        CombinedNozzleEditField_3       matlab.ui.control.NumericEditField
        FuelPumpEditFieldLabel          matlab.ui.control.Label
        FuelPumpEditField               matlab.ui.control.NumericEditField
        OptimizationTab                 matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        fKnobLabel                      matlab.ui.control.Label
        fKnob                           matlab.ui.control.Knob
        fabKnobLabel                    matlab.ui.control.Label
        fabKnob                         matlab.ui.control.Knob
        bKnobLabel                      matlab.ui.control.Label
        bKnob                           matlab.ui.control.Knob
        ResultsTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
    end


    methods (Access = private)
    
        function runCode(app)
            JetPro_Project(app.EngineTypeSwitch.Value, app.BypassNozzleSwitch.Value, app.AmbientTemperatureKEditField.Value,...
                app.AmbientPressurekPaEditField.Value, app.FuelPressurekPaEditField.Value, app.MachNumberEditField.Value, app.FanEditField.Value,...
                app.CompressorEditField.Value, app.CombustorEditField_4.Value, app.AfterburnerEditField.Value, app. NozzleMixerEditField_2.Value, app.FanBypassEditField.Value,...
                app.CompressorBleedEditField.Value, app.CombustorfuelairEditField.Value, app.AfterburnerfuelairEditField.Value,...
                app.MaxTemperatureKEditField.Value, app.AfterburnerMaxTempKEditField.Value, [app.DiffuserEditField_2.Value, app.FanEditField_3.Value,...
                app.CompressorEditField_3.Value, app.CombustorEditField_3.Value, app.TurbineEditField_2.Value, app.TurbineMixerEditField_2.Value,...
                app.FanTurbineEditField_2.Value, app.AfterburnerEditField_3.Value, app.CoreNozzleEditField_2.Value, app.FanNozzleEditField_2.Value,...
                app.NozzleMixerEditField.Value, app.CombinedNozzleEditField_2.Value], [app.DiffuserEditField_3.Value, app.FanEditField_4.Value,...
                app.CompressorEditField_4.Value, app.CombustorEditField_2.Value, app.TurbineEditField_3.Value, app.FanTurbineEditField_3.Value,...
                app.AfterburnerEditField_4.Value, app.CoreNozzleEditField_3.Value, app.FanNozzleEditField_3.Value, app.CombinedNozzleEditField_3.Value,...
                app.FuelPumpEditField.Value], [app.DiffuserEditField.Value, app.FanEditField_2.Value, app.CompressorEditField_2.Value,...
                app.CombustorEditField.Value, app.TurbineEditField.Value, app.TurbineMixerEditField.Value, app.FanTurbineEditField.Value, app.AfterburnerEditField_2.Value,...
                app.CoreNozzleEditField.Value, app.FanNozzleEditField.Value, app.CombinedNozzleEditField.Value], app.FuelHeatingValueMJkgEditField.Value);
            pause(2);
            
            
            [~, ~, raw] = xlsread('Results.xlsx');
            raw(cellfun(@(x) isnumeric(x) && isnan(x), raw)) = {''};
            raw=cell2table(raw);
            
            app.UITable.Data = raw;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.WindowState='maximized';
            app.UIFigure.Resize='on';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            runCode(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('Results.xlsx', 'file')
                selection = questdlg('Delete ''Results'' file?',...
                    'Confirmation',...
                    'Yes','No','Yes');
                switch selection
                    case 'Yes'
                        delete Results.xlsx
                    case 'No'
                        return
                end
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.FlightConditionsPanel = uipanel(app.InitializationTab);
            app.FlightConditionsPanel.Title = 'Flight Conditions';
            app.AmbientTemperatureKEditFieldLabel = uilabel(app.FlightConditionsPanel);
            app.AmbientTemperatureKEditFieldLabel.Text = 'Ambient Temperature [K]';
            app.AmbientTemperatureKEditField = uieditfield(app.FlightConditionsPanel, 'numeric');
            app.AmbientTemperatureKEditField.Limits = [0 1000];
            app.AmbientTemperatureKEditField.Value = 220;
            app.AmbientPressurekPaEditFieldLabel = uilabel(app.FlightConditionsPanel);
            app.AmbientPressurekPaEditFieldLabel.Text = 'Ambient Pressure [kPa]';
            app.AmbientPressurekPaEditField = uieditfield(app.FlightConditionsPanel, 'numeric');
            app.AmbientPressurekPaEditField.Limits = [0 300];
            app.AmbientPressurekPaEditField.Value = 100;
            app.MachNumberEditFieldLabel = uilabel(app.FlightConditionsPanel);
            app.MachNumberEditFieldLabel.Text = 'Mach Number';
            app.MachNumberEditField = uieditfield(app.FlightConditionsPanel, 'numeric');
            app.MachNumberEditField.Limits = [0 5];
            app.MachNumberEditField.Value = 1.5;
            app.FuelPressurekPaEditFieldLabel = uilabel(app.FlightConditionsPanel);
            app.FuelPressurekPaEditFieldLabel.Text = 'Fuel Pressure [kPa]';
            app.FuelPressurekPaEditField = uieditfield(app.FlightConditionsPanel, 'numeric');
            app.FuelPressurekPaEditField.Limits = [0 300];
            app.FuelPressurekPaEditField.Value = 104;
            app.PressureRatiosPanel = uipanel(app.InitializationTab);
            app.PressureRatiosPanel.Title = 'Pressure Ratios';
            app.FanEditFieldLabel = uilabel(app.PressureRatiosPanel);
            app.FanEditFieldLabel.Text = 'Fan';
            app.FanEditField = uieditfield(app.PressureRatiosPanel, 'numeric');
            app.FanEditField.Limits = [1.1 1.5];
            app.FanEditField.Value = 1.2;
            app.CompressorEditFieldLabel = uilabel(app.PressureRatiosPanel);
            app.CompressorEditFieldLabel.Text = 'Compressor';
            app.CompressorEditField = uieditfield(app.PressureRatiosPanel, 'numeric');
            app.CompressorEditField.Limits = [40 54.5455];
            app.CompressorEditField.Value = 50;
            app.CombustorEditField_4Label = uilabel(app.PressureRatiosPanel);
            app.CombustorEditField_4Label.Text = 'Combustor';
            app.CombustorEditField_4 = uieditfield(app.PressureRatiosPanel, 'numeric');
            app.CombustorEditField_4.Limits = [0 2];
            app.CombustorEditField_4.Value = 0.98;
            app.AfterburnerEditFieldLabel = uilabel(app.PressureRatiosPanel);
            app.AfterburnerEditFieldLabel.Text = 'Afterburner';
            app.AfterburnerEditField = uieditfield(app.PressureRatiosPanel, 'numeric');
            app.AfterburnerEditField.Limits = [0 2];
            app.AfterburnerEditField.Value = 0.97;
            app.NozzleMixerEditField_2Label = uilabel(app.PressureRatiosPanel);
            app.NozzleMixerEditField_2Label.Text = 'Nozzle Mixer';
            app.NozzleMixerEditField_2 = uieditfield(app.PressureRatiosPanel, 'numeric');
            app.NozzleMixerEditField_2.Limits = [0 1];
            app.NozzleMixerEditField_2.Value = 0.8;
            app.MassRatiosPanel = uipanel(app.InitializationTab);
            app.MassRatiosPanel.Title = 'Mass Ratios';
            app.CombustorfuelairEditFieldLabel = uilabel(app.MassRatiosPanel);
            app.CombustorfuelairEditFieldLabel.Text = 'Combustor fuel-air';
            app.CombustorfuelairEditField = uieditfield(app.MassRatiosPanel, 'numeric');
            app.CombustorfuelairEditField.Limits = [0 0.5];
            app.CombustorfuelairEditField.Value = 0.018;
            app.AfterburnerfuelairEditFieldLabel = uilabel(app.MassRatiosPanel);
            app.AfterburnerfuelairEditFieldLabel.Text = 'Afterburner fuel-air';
            app.AfterburnerfuelairEditField = uieditfield(app.MassRatiosPanel, 'numeric');
            app.AfterburnerfuelairEditField.Limits = [0 0.5];
            app.AfterburnerfuelairEditField.Value = 0.01;
            app.FanBypassEditFieldLabel = uilabel(app.MassRatiosPanel);
            app.FanBypassEditFieldLabel.Text = 'Fan Bypass';
            app.FanBypassEditField = uieditfield(app.MassRatiosPanel, 'numeric');
            app.FanBypassEditField.Limits = [0 20];
            app.FanBypassEditField.Value = 2;
            app.CompressorBleedEditFieldLabel = uilabel(app.MassRatiosPanel);
            app.CompressorBleedEditFieldLabel.Text = 'Compressor Bleed';
            app.CompressorBleedEditField = uieditfield(app.MassRatiosPanel, 'numeric');
            app.CompressorBleedEditField.Limits = [0 1];
            app.CompressorBleedEditField.Value = 0.1;
            app.CalculateButton = uibutton(app.InitializationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CombustionChamberPanel = uipanel(app.InitializationTab);
            app.CombustionChamberPanel.Title = 'Combustion Chamber';
            app.MaxTemperatureKEditFieldLabel = uilabel(app.CombustionChamberPanel);
            app.MaxTemperatureKEditFieldLabel.Text = 'Max Temperature [K]';
            app.MaxTemperatureKEditField = uieditfield(app.CombustionChamberPanel, 'numeric');
            app.MaxTemperatureKEditField.Limits = [0 5000];
            app.MaxTemperatureKEditField.Value = 1700;
            app.AfterburnerMaxTempKEditFieldLabel = uilabel(app.CombustionChamberPanel);
            app.AfterburnerMaxTempKEditFieldLabel.Text = 'Afterburner Max Temp [K]';
            app.AfterburnerMaxTempKEditField = uieditfield(app.CombustionChamberPanel, 'numeric');
            app.AfterburnerMaxTempKEditField.Limits = [0 5000];
            app.AfterburnerMaxTempKEditField.Value = 2200;
            app.FuelHeatingValueMJkgEditFieldLabel = uilabel(app.CombustionChamberPanel);
            app.FuelHeatingValueMJkgEditFieldLabel.Text = 'Fuel Heating Value [MJ/kg]';
            app.FuelHeatingValueMJkgEditField = uieditfield(app.CombustionChamberPanel, 'numeric');
            app.FuelHeatingValueMJkgEditField.Limits = [0 1000];
            app.FuelHeatingValueMJkgEditField.Value = 45;
            app.BypassNozzleSwitchLabel = uilabel(app.InitializationTab);
            app.BypassNozzleSwitchLabel.Text = 'Bypass Nozzle';
            app.BypassNozzleSwitch = uiswitch(app.InitializationTab, 'slider');
            app.BypassNozzleSwitch.Items = {'Separate', 'Mixed'};
            app.BypassNozzleSwitch.Value = 'Separate';
            app.EngineTypeSwitchLabel = uilabel(app.InitializationTab);
            app.EngineTypeSwitchLabel.Text = 'Engine Type';
            app.EngineTypeSwitch = uiswitch(app.InitializationTab, 'slider');
            app.EngineTypeSwitch.Items = {'Turbofan', 'Turbojet'};
            app.EngineTypeSwitch.Value = 'Turbofan';
            app.ConstantsTab = uitab(app.TabGroup);
            app.ConstantsTab.Title = 'Constants';
            app.SpecificHeatRatioPanel = uipanel(app.ConstantsTab);
            app.SpecificHeatRatioPanel.Title = 'Specific Heat Ratio';
            app.DiffuserEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.DiffuserEditFieldLabel.Text = 'Diffuser';
            app.DiffuserEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.DiffuserEditField.Limits = [1 1.8];
            app.DiffuserEditField.Value = 1.4;
            app.FanEditField_2Label = uilabel(app.SpecificHeatRatioPanel);
            app.FanEditField_2Label.Text = 'Fan';
            app.FanEditField_2 = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.FanEditField_2.Limits = [1 1.8];
            app.FanEditField_2.Value = 1.4;
            app.CompressorEditField_2Label = uilabel(app.SpecificHeatRatioPanel);
            app.CompressorEditField_2Label.Text = 'Compressor';
            app.CompressorEditField_2 = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.CompressorEditField_2.Limits = [1 1.8];
            app.CompressorEditField_2.Value = 1.38;
            app.CombustorEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.CombustorEditFieldLabel.Text = 'Combustor';
            app.CombustorEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.CombustorEditField.Limits = [1 1.8];
            app.CombustorEditField.Value = 1.33;
            app.TurbineEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.TurbineEditFieldLabel.Text = 'Turbine';
            app.TurbineEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.TurbineEditField.Limits = [1 1.8];
            app.TurbineEditField.Value = 1.33;
            app.TurbineMixerEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.TurbineMixerEditFieldLabel.Text = 'Turbine Mixer';
            app.TurbineMixerEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.TurbineMixerEditField.Limits = [1 1.8];
            app.TurbineMixerEditField.Value = 1.34;
            app.FanTurbineEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.FanTurbineEditFieldLabel.Text = 'Fan Turbine';
            app.FanTurbineEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.FanTurbineEditField.Limits = [1 1.8];
            app.FanTurbineEditField.Value = 1.33;
            app.AfterburnerEditField_2Label = uilabel(app.SpecificHeatRatioPanel);
            app.AfterburnerEditField_2Label.Text = 'Afterburner';
            app.AfterburnerEditField_2 = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.AfterburnerEditField_2.Limits = [1 1.8];
            app.AfterburnerEditField_2.Value = 1.32;
            app.CoreNozzleEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.CoreNozzleEditFieldLabel.Text = 'Core Nozzle';
            app.CoreNozzleEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.CoreNozzleEditField.Limits = [1 1.8];
            app.CoreNozzleEditField.Value = 1.35;
            app.FanNozzleEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.FanNozzleEditFieldLabel.Text = 'Fan Nozzle';
            app.FanNozzleEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.FanNozzleEditField.Limits = [1 1.8];
            app.FanNozzleEditField.Value = 1.4;
            app.CombinedNozzleEditFieldLabel = uilabel(app.SpecificHeatRatioPanel);
            app.CombinedNozzleEditFieldLabel.Text = 'Combined Nozzle';
            app.CombinedNozzleEditField = uieditfield(app.SpecificHeatRatioPanel, 'numeric');
            app.CombinedNozzleEditField.Limits = [1 1.8];
            app.CombinedNozzleEditField.Value = 1.37;
            app.MolecularWeightsgmolPanel = uipanel(app.ConstantsTab);
            app.MolecularWeightsgmolPanel.Title = 'Molecular Weights [g/mol]';
            app.DiffuserEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.DiffuserEditField_2Label.Text = 'Diffuser';
            app.DiffuserEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.DiffuserEditField_2.Limits = [0 Inf];
            app.DiffuserEditField_2.Value = 28.8;
            app.FanEditField_3Label = uilabel(app.MolecularWeightsgmolPanel);
            app.FanEditField_3Label.Text = 'Fan';
            app.FanEditField_3 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.FanEditField_3.Limits = [0 Inf];
            app.FanEditField_3.Value = 28.8;
            app.CompressorEditField_3Label = uilabel(app.MolecularWeightsgmolPanel);
            app.CompressorEditField_3Label.Text = 'Compressor';
            app.CompressorEditField_3 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.CompressorEditField_3.Limits = [0 Inf];
            app.CompressorEditField_3.Value = 28.8;
            app.CombustorEditField_3Label = uilabel(app.MolecularWeightsgmolPanel);
            app.CombustorEditField_3Label.Text = 'Combustor';
            app.CombustorEditField_3 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.CombustorEditField_3.Limits = [0 Inf];
            app.CombustorEditField_3.Value = 28.8;
            app.TurbineEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.TurbineEditField_2Label.Text = 'Turbine';
            app.TurbineEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.TurbineEditField_2.Limits = [0 Inf];
            app.TurbineEditField_2.Value = 28.8;
            app.TurbineMixerEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.TurbineMixerEditField_2Label.Text = 'Turbine Mixer';
            app.TurbineMixerEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.TurbineMixerEditField_2.Limits = [0 Inf];
            app.TurbineMixerEditField_2.Value = 28.8;
            app.FanTurbineEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.FanTurbineEditField_2Label.Text = 'Fan Turbine';
            app.FanTurbineEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.FanTurbineEditField_2.Limits = [0 Inf];
            app.FanTurbineEditField_2.Value = 28.8;
            app.AfterburnerEditField_3Label = uilabel(app.MolecularWeightsgmolPanel);
            app.AfterburnerEditField_3Label.Text = 'Afterburner';
            app.AfterburnerEditField_3 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.AfterburnerEditField_3.Limits = [0 Inf];
            app.AfterburnerEditField_3.Value = 28.8;
            app.CoreNozzleEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.CoreNozzleEditField_2Label.Text = 'Core Nozzle';
            app.CoreNozzleEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.CoreNozzleEditField_2.Limits = [0 Inf];
            app.CoreNozzleEditField_2.Value = 28.8;
            app.FanNozzleEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.FanNozzleEditField_2Label.Text = 'Fan Nozzle';
            app.FanNozzleEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.FanNozzleEditField_2.Limits = [0 Inf];
            app.FanNozzleEditField_2.Value = 28.8;
            app.NozzleMixerEditFieldLabel = uilabel(app.MolecularWeightsgmolPanel);
            app.NozzleMixerEditFieldLabel.Text = 'Nozzle Mixer';
            app.NozzleMixerEditField = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.NozzleMixerEditField.Limits = [0 Inf];
            app.NozzleMixerEditField.Value = 28.8;
            app.CombinedNozzleEditField_2Label = uilabel(app.MolecularWeightsgmolPanel);
            app.CombinedNozzleEditField_2Label.Text = 'Combined Nozzle';
            app.CombinedNozzleEditField_2 = uieditfield(app.MolecularWeightsgmolPanel, 'numeric');
            app.CombinedNozzleEditField_2.Limits = [0 Inf];
            app.CombinedNozzleEditField_2.Value = 28.8;
            app.EfficienciesPanel = uipanel(app.ConstantsTab);
            app.EfficienciesPanel.Title = 'Efficiencies';
            app.DiffuserEditField_3Label = uilabel(app.EfficienciesPanel);
            app.DiffuserEditField_3Label.Text = 'Diffuser';
            app.DiffuserEditField_3 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.DiffuserEditField_3.Limits = [0 1];
            app.DiffuserEditField_3.Value = 0.92;
            app.FanEditField_4Label = uilabel(app.EfficienciesPanel);
            app.FanEditField_4Label.Text = 'Fan';
            app.FanEditField_4 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.FanEditField_4.Limits = [0 1];
            app.FanEditField_4.Value = 0.9;
            app.CoreNozzleEditField_3Label = uilabel(app.EfficienciesPanel);
            app.CoreNozzleEditField_3Label.Text = 'Core Nozzle';
            app.CoreNozzleEditField_3 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.CoreNozzleEditField_3.Limits = [0 1];
            app.CoreNozzleEditField_3.Value = 0.95;
            app.CompressorEditField_4Label = uilabel(app.EfficienciesPanel);
            app.CompressorEditField_4Label.Text = 'Compressor';
            app.CompressorEditField_4 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.CompressorEditField_4.Limits = [0 1];
            app.CompressorEditField_4.Value = 0.9;
            app.CombustorEditField_2Label = uilabel(app.EfficienciesPanel);
            app.CombustorEditField_2Label.Text = 'Combustor';
            app.CombustorEditField_2 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.CombustorEditField_2.Limits = [0 1];
            app.CombustorEditField_2.Value = 0.99;
            app.TurbineEditField_3Label = uilabel(app.EfficienciesPanel);
            app.TurbineEditField_3Label.Text = 'Turbine';
            app.TurbineEditField_3 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.TurbineEditField_3.Limits = [0 1];
            app.TurbineEditField_3.Value = 0.92;
            app.FanTurbineEditField_3Label = uilabel(app.EfficienciesPanel);
            app.FanTurbineEditField_3Label.Text = 'Fan Turbine';
            app.FanTurbineEditField_3 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.FanTurbineEditField_3.Limits = [0 1];
            app.FanTurbineEditField_3.Value = 0.92;
            app.AfterburnerEditField_4Label = uilabel(app.EfficienciesPanel);
            app.AfterburnerEditField_4Label.Text = 'Afterburner';
            app.AfterburnerEditField_4 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.AfterburnerEditField_4.Limits = [0 1];
            app.AfterburnerEditField_4.Value = 0.96;
            app.FanNozzleEditField_3Label = uilabel(app.EfficienciesPanel);
            app.FanNozzleEditField_3Label.Text = 'Fan Nozzle';
            app.FanNozzleEditField_3 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.FanNozzleEditField_3.Limits = [0 1];
            app.FanNozzleEditField_3.Value = 0.97;
            app.CombinedNozzleEditField_3Label = uilabel(app.EfficienciesPanel);
            app.CombinedNozzleEditField_3Label.Text = 'Combined Nozzle';
            app.CombinedNozzleEditField_3 = uieditfield(app.EfficienciesPanel, 'numeric');
            app.CombinedNozzleEditField_3.Limits = [0 1];
            app.CombinedNozzleEditField_3.Value = 0.95;
            app.FuelPumpEditFieldLabel = uilabel(app.EfficienciesPanel);
            app.FuelPumpEditFieldLabel.Text = 'Fuel Pump';
            app.FuelPumpEditField = uieditfield(app.EfficienciesPanel, 'numeric');
            app.FuelPumpEditField.Limits = [0 1];
            app.FuelPumpEditField.Value = 0.35;
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.UIAxes = uiaxes(app.OptimizationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Mach')
            ylabel(app.UIAxes, 'ST, TSFC')
            app.fKnobLabel = uilabel(app.OptimizationTab);
            app.fKnobLabel.Text = 'f';
            app.fKnob = uiknob(app.OptimizationTab, 'continuous');
            app.fabKnobLabel = uilabel(app.OptimizationTab);
            app.fabKnobLabel.Text = 'fab';
            app.fabKnob = uiknob(app.OptimizationTab, 'continuous');
            app.bKnobLabel = uilabel(app.OptimizationTab);
            app.bKnobLabel.Text = 'b';
            app.bKnob = uiknob(app.OptimizationTab, 'continuous');
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end